classdef mainapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        NewMenu                      matlab.ui.container.Menu
        HelpMenu                     matlab.ui.container.Menu
        Toolbar                      matlab.ui.container.Toolbar
        PushTool                     matlab.ui.container.toolbar.PushTool
        PushTool2                    matlab.ui.container.toolbar.PushTool
        EnterButton                  matlab.ui.control.Button
        dmEditField                  matlab.ui.control.NumericEditField
        dmEditFieldLabel             matlab.ui.control.Label
        OutputsPanel                 matlab.ui.container.Panel
        dDropDown                    matlab.ui.control.DropDown
        dDropDownLabel               matlab.ui.control.Label
        ReceivedPowerEditField       matlab.ui.control.NumericEditField
        ReceivedPowerEditFieldLabel  matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
        ParametersInputPanel         matlab.ui.container.Panel
        SubmitButton                 matlab.ui.control.Button
        PowerUnitsDropDown           matlab.ui.control.DropDown
        PowerUnitsDropDownLabel      matlab.ui.control.Label
        ModelParametersPanel         matlab.ui.container.Panel
        dmListBox                    matlab.ui.control.ListBox
        dmListBoxLabel               matlab.ui.control.Label
        SamplesEditField             matlab.ui.control.NumericEditField
        SamplesEditFieldLabel        matlab.ui.control.Label
        EditField                    matlab.ui.control.NumericEditField
        EditFieldLabel               matlab.ui.control.Label
        nEditField                   matlab.ui.control.NumericEditField
        nEditFieldLabel              matlab.ui.control.Label
        domEditField                 matlab.ui.control.NumericEditField
        domEditFieldLabel            matlab.ui.control.Label
        ExtraParametersPanel         matlab.ui.container.Panel
        TimeEditField                matlab.ui.control.NumericEditField
        TimeEditFieldLabel           matlab.ui.control.Label
        RequiredPREditField          matlab.ui.control.NumericEditField
        RequiredPREditFieldLabel     matlab.ui.control.Label
        AntennaParametersPanel       matlab.ui.container.Panel
        FMHzEditField                matlab.ui.control.NumericEditField
        FMHzEditFieldLabel           matlab.ui.control.Label
        GrEditField                  matlab.ui.control.NumericEditField
        GrEditFieldLabel             matlab.ui.control.Label
        GtEditField                  matlab.ui.control.NumericEditField
        GtEditFieldLabel             matlab.ui.control.Label
        PtEditField                  matlab.ui.control.NumericEditField
        PtEditFieldLabel             matlab.ui.control.Label
        CommandWindowLabel           matlab.ui.control.Label
        CommandWindowTextArea        matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        d_array % Description
        CallingApp % login App
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, password)
            app.CallingApp  = caller;
            
            % Process the password
            pw = passwordUI('Query', 'AlphaNum');
            % Counter for wrong pw entries
            WrongCount = 0;
            
            while strcmp(pw,password) == 0
                WrongCount = WrongCount + 1;
                if WrongCount == 3
                    delete(app)
                    break
                end
                msgbox("Wrong Password!","Error","custom",imread("bedo.png"));
                pw = passwordUI('Query', 'AlphaNum');
            end
            if strcmp(pw,password) == 1
                
                f = msgbox("Welcome Back");
            end
            
            
            %app.d_array = {" "};
        end

        % Clicked callback: PushTool
        function PushToolClicked(app, event)
%             t = "ahmed";
%             app.CommandWindowTextArea.Editable = false;
%             

              file = jsondecode(fileread('parameters.json'));
              
              app.PtEditField.Value = file.AntennaParameters.Pt;
              app.GtEditField.Value = file.AntennaParameters.Gt;
              app.GrEditField.Value = file.AntennaParameters.Gr;
              app.FMHzEditField.Value  = file.AntennaParameters.F;

              app.dmEditField.Value  = file.ModelParameters.d;
              app.domEditField.Value = file.ModelParameters.do;
              app.nEditField.Value  = file.ModelParameters.n;
              app.EditField.Value   = file.ModelParameters.x_;          
              app.SamplesEditField.Value  = file.ModelParameters.k;
              
              app.TimeEditField.Value   = file.ExtraParameters.RequiredPR;          
              app.RequiredPREditField.Value  = file.ExtraParameters.Time;    
              
              
              %Check if one them is zero


              %Output on command window
              app.CommandWindowTextArea.Value = sprintf('[Obelos]: File is Imported Successfully!');  
              app.CommandWindowTextArea.FontColor = [0.47,0.67,0.19];  %Green


        end

        % Clicked callback: PushTool2
        function PushTool2Clicked(app, event)
                file2.AntennaParameters.Pt = app.PtEditField.Value ;
                file2.AntennaParameters.Gt = app.GtEditField.Value ;
                file2.AntennaParameters.Gr = app.GrEditField.Value ;
                file2.AntennaParameters.F  = app.FMHzEditField.Value ;
                
                file2.ModelParameters.d  = app.dmEditField.Value;
                file2.ModelParameters.do = app.domEditField.Value;
                file2.ModelParameters.n  = app.nEditField.Value;
                file2.ModelParameters.x_ = app.EditField.Value;          
                file2.ModelParameters.k  = app.SamplesEditField.Value;
                
                file2.ExtraParameters.RequiredPR = app.TimeEditField.Value;          
                file2.ExtraParameters.Time = app.RequiredPREditField.Value; 
                 
                file_json = jsonencode(file2,'PrettyPrint',true);
                
                fileID = fopen('obelos.json','w');
                fprintf(fileID,'%s',file_json);
                fclose(fileID);


              %Output on command window
              app.CommandWindowTextArea.Value = sprintf('[Obelos]: File is Exported Successfully!');  
              app.CommandWindowTextArea.FontColor = [0.47,0.67,0.19];
        end

        % Value changed function: PowerUnitsDropDown
        function PowerUnitsDropDownValueChanged(app, event)
            value = app.PowerUnitsDropDown.Value;
            
            app.CommandWindowTextArea.Value = sprintf('[Obelos]: Power Units are %s',value); 
        end

        % Value changed function: ReceivedPowerEditField
        function ReceivedPowerEditFieldValueChanged(app, event)
            value = app.ReceivedPowerEditField.Value;
            
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            powerunits = app.PowerUnitsDropDown.Value;
            
            Pr_do_decimal = app.PtEditField.Value *  app.GtEditField.Value * app.GrEditField.Value * ( (300000000 / (app.FMHzEditField.Value*10^6) ) / (4 * pi * app.domEditField.Value ) ) ^ 2;
                
            Pr_d_decimal =  Pr_do_decimal * (app.domEditField.Value / app.dmEditField.Value ) ^  app.nEditField.Value ;
            
            PL_do_decimal = app.PtEditField.Value / Pr_do_decimal ;
            
            Mean = PL_do_decimal + 10 * app.nEditField.Value * log10(app.dmEditField.Value/app.domEditField.Value);
            
            PL_d_decimal = Mean + app.EditField.Value .* randn(app.SamplesEditField.Value,1) ;
            
            scatter(app.UIAxes,app.dmEditField.Value,PL_d_decimal);
            
            app.CommandWindowTextArea.Value = sprintf('[Obelos]: Figure is Plotted '); 


            app.UIAxes.Title.String = sprintf("Scatter Plot for n = %d" , app.nEditField.Value);
            
            temp = app.CommandWindowTextArea.Value;
            
            if strcmp(powerunits,"dB")
                
             app.ReceivedPowerEditField.Value = 10*log10(Pr_d_decimal) ; 


             app.CommandWindowTextArea.Value = sprintf('[Obelos]: Received Power is Calculated %d dB',app.ReceivedPowerEditField.Value); 


            else
                
             app.ReceivedPowerEditField.Value = Pr_d_decimal ; 
             
             app.CommandWindowTextArea.Value = sprintf("\n[Obelos]: Received Power is Calculated %d decimal",app.ReceivedPowerEditField.Value);
             
             app.CommandWindowTextArea.Value = strcat(temp,app.CommandWindowTextArea.Value);
             
            end
            
            
            %app.dmListBox.Items = {'100','200'};
            app.dDropDown.Items = {'100','200'};
            app.dDropDown.Value = '100';
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            if app.dmEditField.Value > app.domEditField.Value 
                
                app.d_array = {app.d_array, int2str(app.dmEditField.Value) };
                
                
            %    app.domEditField.Value = app.d_array(1);
                
                
                app.dmListBox.Items = cellstr(app.d_array);
                 
                app.CommandWindowTextArea.Value = sprintf("[Obelos]: Distance d %d is added ",app.dmEditField.Value);
                
            else
                app.CommandWindowTextArea.Value = sprintf("[Obelos]: Distance d %s is less than do ",app.dmEditField.Value);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % reEnable the button in login app
            %app.CallingApp.CreateButton.Enable = "on";
            
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.Checked = 'on';
            app.NewMenu.Text = 'New';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.Tooltip = {'Import Parameters'};
            app.PushTool.ClickedCallback = createCallbackFcn(app, @PushToolClicked, true);
            app.PushTool.Icon = 'Capture.PNG';
            app.PushTool2 = uipushtool(app.Toolbar);
            app.PushTool2.Tooltip = {'Export Parameters'};
            app.PushTool2.ClickedCallback = createCallbackFcn(app, @PushTool2Clicked, true);
            app.PushTool2.Icon = 'Capture2.PNG';
            app.CommandWindowTextArea = uitextarea(app.UIFigure);
            app.CommandWindowTextArea.Editable = 'off';
            app.CommandWindowTextArea.Placeholder = 'Welcome to Obelos!';
            app.CommandWindowLabel = uilabel(app.UIFigure);
            app.CommandWindowLabel.Text = 'Command Window';
            app.ParametersInputPanel = uipanel(app.UIFigure);
            app.ParametersInputPanel.Title = 'Parameters Input';
            app.AntennaParametersPanel = uipanel(app.ParametersInputPanel);
            app.AntennaParametersPanel.Title = 'Antenna Parameters';
            app.PtEditFieldLabel = uilabel(app.AntennaParametersPanel);
            app.PtEditFieldLabel.Text = 'Pt ';
            app.PtEditField = uieditfield(app.AntennaParametersPanel, 'numeric');
            app.GtEditFieldLabel = uilabel(app.AntennaParametersPanel);
            app.GtEditFieldLabel.Text = 'Gt';
            app.GtEditField = uieditfield(app.AntennaParametersPanel, 'numeric');
            app.GrEditFieldLabel = uilabel(app.AntennaParametersPanel);
            app.GrEditFieldLabel.Text = 'Gr';
            app.GrEditField = uieditfield(app.AntennaParametersPanel, 'numeric');
            app.FMHzEditFieldLabel = uilabel(app.AntennaParametersPanel);
            app.FMHzEditFieldLabel.Text = 'F (MHz)';
            app.FMHzEditField = uieditfield(app.AntennaParametersPanel, 'numeric');
            app.ExtraParametersPanel = uipanel(app.ParametersInputPanel);
            app.ExtraParametersPanel.Title = 'Extra Parameters';
            app.RequiredPREditFieldLabel = uilabel(app.ExtraParametersPanel);
            app.RequiredPREditFieldLabel.Text = 'Required PR';
            app.RequiredPREditField = uieditfield(app.ExtraParametersPanel, 'numeric');
            app.TimeEditFieldLabel = uilabel(app.ExtraParametersPanel);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.ExtraParametersPanel, 'numeric');
            app.ModelParametersPanel = uipanel(app.ParametersInputPanel);
            app.ModelParametersPanel.Title = 'Model Parameters';
            app.domEditFieldLabel = uilabel(app.ModelParametersPanel);
            app.domEditFieldLabel.Text = 'do (m)';
            app.domEditField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.nEditFieldLabel = uilabel(app.ModelParametersPanel);
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.EditFieldLabel = uilabel(app.ModelParametersPanel);
            app.EditFieldLabel.Text = 'σ';
            app.EditField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.SamplesEditFieldLabel = uilabel(app.ModelParametersPanel);
            app.SamplesEditFieldLabel.Text = 'Samples';
            app.SamplesEditField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.dmListBoxLabel = uilabel(app.ModelParametersPanel);
            app.dmListBoxLabel.Text = 'd (m)';
            app.dmListBox = uilistbox(app.ModelParametersPanel);
            app.dmListBox.Items = {};
            app.dmListBox.Value = {};
            app.PowerUnitsDropDownLabel = uilabel(app.ParametersInputPanel);
            app.PowerUnitsDropDownLabel.Text = 'Power Units';
            app.PowerUnitsDropDown = uidropdown(app.ParametersInputPanel);
            app.PowerUnitsDropDown.Items = {'decimal', 'dB'};
            app.PowerUnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @PowerUnitsDropDownValueChanged, true);
            app.PowerUnitsDropDown.Value = 'decimal';
            app.SubmitButton = uibutton(app.ParametersInputPanel, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.OutputsPanel = uipanel(app.UIFigure);
            app.OutputsPanel.Title = 'Outputs';
            app.UIAxes = uiaxes(app.OutputsPanel);
            xlabel(app.UIAxes, 'distance d (m)')
            ylabel(app.UIAxes, 'Path Loss')
            zlabel(app.UIAxes, 'Z')
            app.ReceivedPowerEditFieldLabel = uilabel(app.OutputsPanel);
            app.ReceivedPowerEditFieldLabel.Text = 'Received Power';
            app.ReceivedPowerEditField = uieditfield(app.OutputsPanel, 'numeric');
            app.ReceivedPowerEditField.ValueChangedFcn = createCallbackFcn(app, @ReceivedPowerEditFieldValueChanged, true);
            app.dDropDownLabel = uilabel(app.OutputsPanel);
            app.dDropDownLabel.Text = 'd';
            app.dDropDown = uidropdown(app.OutputsPanel);
            app.dDropDown.Items = {'15', '12', '13'};
            app.dDropDown.Value = '15';
            app.dmEditFieldLabel = uilabel(app.UIFigure);
            app.dmEditFieldLabel.Text = 'd (m)';
            app.dmEditField = uieditfield(app.UIFigure, 'numeric');
            app.EnterButton = uibutton(app.UIFigure, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainapp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end